classdef DAQ_App_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        EMGGridLayout                matlab.ui.container.Tab
        EMGYMinEditField             matlab.ui.control.NumericEditField
        YminLabel_3                  matlab.ui.control.Label
        EMGYMaxEditField             matlab.ui.control.NumericEditField
        YmaxLabel_3                  matlab.ui.control.Label
        EMGChannelListBox            matlab.ui.control.ListBox
        EMGChannelsLabel             matlab.ui.control.Label
        EMGAxes_4                    matlab.ui.control.UIAxes
        EMGAxes_3                    matlab.ui.control.UIAxes
        EMGAxes_2                    matlab.ui.control.UIAxes
        EMGAxes_1                    matlab.ui.control.UIAxes
        AnalogGridLayout             matlab.ui.container.Tab
        AnalogYMinEditField          matlab.ui.control.NumericEditField
        YminLabel_2                  matlab.ui.control.Label
        AnalogYMaxEditField          matlab.ui.control.NumericEditField
        YmaxLabel_2                  matlab.ui.control.Label
        AnalogChannelListBox         matlab.ui.control.ListBox
        AnalogChannelListBoxLabel    matlab.ui.control.Label
        AnalogAxes_2                 matlab.ui.control.UIAxes
        AnalogAxes_1                 matlab.ui.control.UIAxes
        CombinedGridLayout           matlab.ui.container.Tab
        CombinedYMaxEditField        matlab.ui.control.NumericEditField
        YmaxLabel                    matlab.ui.control.Label
        CombinedYMinEditField        matlab.ui.control.NumericEditField
        YminLabel                    matlab.ui.control.Label
        CombinedChannelListBox       matlab.ui.control.ListBox
        CombinedChannelListBoxLabel  matlab.ui.control.Label
        CombinedAxes                 matlab.ui.control.UIAxes
        UIPanel                      matlab.ui.container.Panel
        StatusLabel                  matlab.ui.control.Label
        StatusLamp                   matlab.ui.control.Lamp
        StopButton                   matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        InitializeButton             matlab.ui.control.Button
        PathStatusLabel              matlab.ui.control.Label
        SelectPathButton             matlab.ui.control.Button
        SubjectIDEditField           matlab.ui.control.EditField
        SubjectIDLabel               matlab.ui.control.Label
        FileStringEditField          matlab.ui.control.EditField
        SessionNameLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        % DAQ and Dragonfly Objects
        dq               % DAQ object for data acquisition
        DF               % Dragonfly object
        Fs = 2500        % Sampling rate

        % File I/O and Metadata
        SubID            % Subject ID
        Filestr          % File string for session name
        TrlNum = 1       % Trial number, starts at 1
        pathname         % Directory to save data
        meta_path        % Directory for metadata
        OPfile
        
        % App State
        isInitialized = false % Flag to check if app is ready
        
        % Plotting Handles and Buffers
        emgPlotHandles   % Handles for the 4 EMG line plots
        analogPlotHandles % Handles for the 2 analog line plots
        plotBuffer       % FIXED-SIZE circular buffer for plotting
        timeBuffer       % FIXED-SIZE time vector for the plot buffer
        emgAxes          % Handles to the EMG UIAxes
        analogAxes       % Handles to the Analog UIAxes
        fid

        EMG_CH
        muscle_labels
        analog_input_labels
        combinedPlotHandles  % Handles for the 6 combined line plots
    end

    methods (Access = private)

        % Centralized plot setup
        function setupPlots(app)
            % Pre-allocate handles
            app.emgPlotHandles = gobjects(1, 4);
            app.analogPlotHandles = gobjects(1, 2);
            app.combinedPlotHandles = gobjects(1, 6);

            % Setup individual EMG plots
            for i = 1:4
                app.emgPlotHandles(i) = plot(app.emgAxes(i), NaN, NaN, 'b-');
            end

            % Setup individual Analog plots
            for i = 1:2
                app.analogPlotHandles(i) = plot(app.analogAxes(i), NaN, NaN, 'r-');
            end

            % Setup Combined plot
            hold(app.CombinedAxes, 'on');
            colors = {'#0072BD', '#D95319', '#EDB120', '#7E2F8E', '#77AC30', '#4DBEEE'};
            lineStyles = {'-', '-', '-', '-', '--', '--'};
            for i = 1:6
                app.combinedPlotHandles(i) = plot(app.CombinedAxes, NaN, NaN, ...
                    'Color', colors{i}, 'LineStyle', lineStyles{i}, 'LineWidth', 1.5);
            end
            title(app.CombinedAxes, 'Combined EMG & Analog Signals');
            xlabel(app.CombinedAxes, 'Time (s)');
            ylabel(app.CombinedAxes, 'Amplitude');
            grid(app.CombinedAxes, 'off');
            hold(app.CombinedAxes, 'off');
        end

        % Main DAQ callback - now very simple
        function logBroadcastAndPlotData(app, src, ~)
            % 1. Read data and write to file
            [data, timestamps, ~] = read(src, src.ScansAvailableFcnCount, "OutputFormat", "Matrix");
            fwrite(app.fid, [timestamps, data]', 'double');
            
            % 2. Update the circular plot buffer
            newSamples = size(data, 1);
            app.plotBuffer = [app.plotBuffer(newSamples+1:end, :); data];
            
            % 3. Call the centralized plotting function
            updatePlots(app);
            
            drawnow('limitrate');
        end
        
        % Centralized function for all real-time plotting logic
        function updatePlots(app)
            selectedTab = app.TabGroup.SelectedTab;
        
            if selectedTab == app.EMGGridLayout
                selected_labels = app.EMGChannelListBox.Value;
                for i = 1:4
                    ax = app.emgAxes(i);
                    handle = app.emgPlotHandles(i);
                    if i <= numel(selected_labels)
                        label = selected_labels{i};
                        % Use logical indexing to find the correct channel number
                        logical_idx = strcmp(app.muscle_labels, label);
                        ch_num = app.EMG_CH(logical_idx);
                        plotData = app.plotBuffer(:, ch_num) - mean(app.plotBuffer(:, ch_num), 'omitnan');
                        
                        set(handle, 'XData', app.timeBuffer, 'YData', plotData);
                        title(ax, label, 'Interpreter', 'none');
                        applyYLimits(app, ax, app.EMGYMinEditField, app.EMGYMaxEditField);
                    else
                        set(handle, 'XData', NaN, 'YData', NaN');
                        title(ax, ['EMG CH' num2str(i)]);
                    end
                end
        
            elseif selectedTab == app.AnalogGridLayout
                selected_labels = app.AnalogChannelListBox.Value;
                for i = 1:2
                    ax = app.analogAxes(i);
                    handle = app.analogPlotHandles(i);
                    if i <= numel(selected_labels)
                        label = selected_labels{i};
                        ch_idx = find(strcmp(app.analog_input_labels, label), 1);
                        
                        set(handle, 'XData', app.timeBuffer, 'YData', app.plotBuffer(:, ch_idx));
                        title(ax, label, 'Interpreter', 'none');
                        applyYLimits(app, ax, app.AnalogYMinEditField, app.AnalogYMaxEditField);
                    else
                        set(handle, 'XData', NaN, 'YData', NaN');
                        title(ax, ['Analog CH' num2str(i)]);
                    end
                end
        
            elseif selectedTab == app.CombinedGridLayout
                selected_labels = app.CombinedChannelListBox.Value;
                
                for i = 1:6, set(app.combinedPlotHandles(i), 'Visible', 'off'); end

                for i = 1:numel(selected_labels)
                    current_label = selected_labels{i};
                    
                    % Check if EMG or Analog
                    logical_emg_idx = strcmp(app.muscle_labels, current_label);
                    analog_idx = find(strcmp(app.analog_input_labels, current_label), 1);
                    
                    if any(logical_emg_idx)
                        ch_num = app.EMG_CH(logical_emg_idx);
                        plotData = app.plotBuffer(:, ch_num) - mean(app.plotBuffer(:, ch_num), 'omitnan');
                    elseif ~isempty(analog_idx)
                        plotData = app.plotBuffer(:, analog_idx);
                    else
                        continue; % Skip if label not found
                    end
                    set(app.combinedPlotHandles(i), 'XData', app.timeBuffer, 'YData', plotData, 'Visible', 'on');
                end
                
                legend(app.CombinedAxes, selected_labels, 'Location', 'northeast', 'Interpreter', 'none');
                applyYLimits(app, app.CombinedAxes, app.CombinedYMinEditField, app.CombinedYMaxEditField);
            end
        end

        % Helper to keep the Combined list box in sync
        function updateCombinedListBox(app)
            selectedEMG = app.EMGChannelListBox.Value;
            selectedAnalog = app.AnalogChannelListBox.Value;
            combinedItems = [selectedEMG'; selectedAnalog'];
            app.CombinedChannelListBox.Items = combinedItems;
            app.CombinedChannelListBox.Value = combinedItems;
        end
        
        % Helper to apply Y-axis limits
        function applyYLimits(app, axesHandle, yMinField, yMaxField)
            yMin = yMinField.Value;
            yMax = yMaxField.Value;
            if ~isnan(yMin) && ~isnan(yMax) && yMin < yMax
                ylim(axesHandle, [yMin, yMax]);
            else
                ylim(axesHandle, 'auto');
            end
        end

        % Helper to generate the summary plot after stopping
        function generateSummaryPlot(app)
            channelsToPlot = app.CombinedChannelListBox.Value;
            if isempty(channelsToPlot), return; end
            if numel(channelsToPlot) > 6, channelsToPlot = channelsToPlot(1:6); end

            try
                fid_read = fopen(app.OPfile, 'r');
                numTotalChannels = numel(app.dq.Channels);
                rawData = fread(fid_read, [numTotalChannels + 1, inf], 'double');
                fclose(fid_read);
                
                allData = rawData';
                timeVector = allData(:, 1);
                dataMatrix = allData(:, 2:end);

                summaryFig = figure('Name', ['Trial Summary: ' app.Filestr '_Trl' num2str(app.TrlNum)], 'NumberTitle', 'off');
                tiledlayout(summaryFig, 'flow');

                for i = 1:numel(channelsToPlot)
                    current_label = channelsToPlot{i};
                    emg_idx = find(strcmp(app.muscle_labels, current_label));
                    analog_idx = find(strcmp(app.analog_input_labels, current_label));
                    
                    if ~isempty(emg_idx)
                        plotData = dataMatrix(:, app.EMG_CH(emg_idx));
                        plotData = plotData - mean(plotData, 'omitnan');
                    elseif ~isempty(analog_idx)
                        plotData = dataMatrix(:, analog_idx);
                    end
                    
                    nexttile;
                    plot(timeVector, plotData);
                    title(current_label, 'Interpreter', 'none');
                    xlabel('Time (s)'); ylabel('Amplitude');
                    grid on; axis tight;
                end
            catch ME
                warning('DAQ_App:summaryPlotError', 'Could not generate summary plot. Error: %s', ME.message);
            end
        end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load configuration
            if exist('CH_config.json', 'file')
                config_data = jsondecode(fileread('CH_config.json'));
                app.EMG_CH = config_data.EMG_CH;
                app.muscle_labels = config_data.muscle_labels;
                app.analog_input_labels = config_data.analog_input_labels;
            else
                % Default configuration
                app.EMG_CH = [17,27,29,23,41,43,37,47,57,51,61,63,65,75,77,71];
                app.muscle_labels = arrayfun(@(n) sprintf('EMG %d', n), 1:16, 'UniformOutput', false);
                app.analog_input_labels = arrayfun(@(n) sprintf('Analog %d', n), 1:16, 'UniformOutput', false);
            end

            % Configure UI components
            app.EMGChannelListBox.Items = app.muscle_labels;
            app.EMGChannelListBox.Value = app.muscle_labels(1:4);
            app.AnalogChannelListBox.Items = app.analog_input_labels;
            app.AnalogChannelListBox.Value = app.analog_input_labels(1:2);
            app.EMGChannelListBox.ValueChangedFcn = @(~,~) updateCombinedListBox(app);
            app.AnalogChannelListBox.ValueChangedFcn = @(~,~) updateCombinedListBox(app);
            
            % Setup plots and initial state
            app.emgAxes = [app.EMGAxes_1, app.EMGAxes_2, app.EMGAxes_3, app.EMGAxes_4];
            app.analogAxes = [app.AnalogAxes_1, app.AnalogAxes_2];
            updateCombinedListBox(app);
            setupPlots(app);
            
            app.StartButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            app.PathStatusLabel.Text = 'Path not selected';
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            app.StatusLamp.Color = 'yellow';
            app.StatusLabel.Text = 'Connecting...';
            drawnow;
            
            if ~isempty(app.dq) && isvalid(app.dq), stop(app.dq); end
            
            try
                daqreset;
                d = daqlist("ni");
                if isempty(d), error('No NI DAQ devices found.'); end
                deviceID = d{1, "DeviceID"};
                
                app.dq = daq("ni");
                app.dq.Rate = app.Fs;
                addinput(app.dq, deviceID, 0:15, "Voltage");  % Analog
                addinput(app.dq, deviceID, 16:79, "Voltage"); % EMG
                
                app.dq.ScansAvailableFcnCount = app.Fs * 0.1;
                app.dq.ScansAvailableFcn = @(src, evt) logBroadcastAndPlotData(app, src, evt);
                
                app.isInitialized = true;
                app.StartButton.Enable = 'on';
                app.StatusLamp.Color = 'green';
                app.StatusLabel.Text = 'Ready';
            catch ME
                app.StatusLamp.Color = 'red';
                app.StatusLabel.Text = 'Error';
                uialert(app.UIFigure, ['DAQ Error: ' ME.message], "Initialization Failed");
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isempty(app.pathname)
                uialert(app.UIFigure, 'Please select a data path before starting.', 'Path Not Selected');
                return;
            end
            if ~app.isInitialized
                uialert(app.UIFigure, "Please initialize the DAQ first.", "Not Initialized");
                return;
            end
            
            % Create filename
            app.SubID = app.SubjectIDEditField.Value;
            app.Filestr = app.FileStringEditField.Value;
            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
            filename = sprintf('%s_%s_%s_Trl%03d.bin', timestamp, app.SubID, app.Filestr, app.TrlNum);
            app.OPfile = fullfile(app.pathname, filename);
            
            % Open file for writing
            try
                app.fid = fopen(app.OPfile, 'w');
                if app.fid == -1, error('Could not open file for writing.'); end
            catch ME
                uialert(app.UIFigure, ['File Error: ', ME.message], 'Error');
                return;
            end

            % Initialize plot buffers
            bufferDuration = 2;
            bufferSamples = bufferDuration * app.Fs;
            numChannels = numel(app.dq.Channels);
            app.plotBuffer = NaN(bufferSamples, numChannels);
            app.timeBuffer = (0:bufferSamples-1)' / app.Fs;
            
            % Update UI state
            start(app.dq, 'Continuous');
            app.StatusLamp.Color = [0.47, 0.67, 0.19]; % Green
            app.StartButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            app.InitializeButton.Enable = 'off';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if ~isempty(app.dq) && app.dq.Running
                stop(app.dq);
            end
            if ~isempty(app.fid), fclose(app.fid); end
            app.StatusLabel.Text = 'Saved.';
            
            % Generate the summary plot
            generateSummaryPlot(app);
            
            % Save metadata
            meta_data = struct(...
                'sub_id', app.SubID, 'date', date, 'timestamp', datestr(now, 'HHMMSS'), ...
                'exp_name', app.Filestr, 'trl_num', app.TrlNum, 'fs', app.Fs, ...
                'emg_ch_number', app.EMG_CH, 'musc_labels', app.muscle_labels, ...
                'analog_input_labels', app.analog_input_labels, 'analog_in_ch', 1:16);
            
            metadata_filename = strrep(app.OPfile, '.bin', '_METADATA.mat');
            save(fullfile(app.meta_path, metadata_filename), 'meta_data');
            
            % Update UI state
            app.TrlNum = app.TrlNum + 1;
            app.PathStatusLabel.Text = sprintf('Path: %s (Next Trial: %d)', app.pathname, app.TrlNum);
            app.StatusLamp.Color = 'red';
            app.StartButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.InitializeButton.Enable = 'on';
        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(app, event)
            selectedPath = uigetdir(pwd, 'Select Path to Save Data');
            if selectedPath == 0, return; end % User cancelled
            
            app.pathname = selectedPath;
            app.meta_path = fullfile(app.pathname, 'metadata');
            if ~exist(app.meta_path, 'dir'), mkdir(app.meta_path); end
            
            % Logic to find next trial number (assumes a function get_nxt_trialnb exists)
            try
                app.TrlNum = get_nxt_trialnb(app.pathname);
            catch
                app.TrlNum = 1;
            end
            app.PathStatusLabel.Text = sprintf('Path: %s (Next Trial: %d)', app.pathname, app.TrlNum);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIPanel = uipanel(app.UIFigure);
            app.UIPanel.Title = 'Panel';
            app.SessionNameLabel = uilabel(app.UIPanel);
            app.SessionNameLabel.Text = 'Session Name';
            app.FileStringEditField = uieditfield(app.UIPanel, 'text');
            app.SubjectIDLabel = uilabel(app.UIPanel);
            app.SubjectIDLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.UIPanel, 'text');
            app.SelectPathButton = uibutton(app.UIPanel, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButtonPushed, true);
            app.SelectPathButton.Text = 'Select Path';
            app.PathStatusLabel = uilabel(app.UIPanel);
            app.PathStatusLabel.Text = 'Path:';
            app.InitializeButton = uibutton(app.UIPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Connect';
            app.StartButton = uibutton(app.UIPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StatusLamp = uilamp(app.UIPanel);
            app.StatusLabel = uilabel(app.UIPanel);
            app.StatusLabel.Text = 'Status';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EMGGridLayout = uitab(app.TabGroup);
            app.EMGGridLayout.Title = 'EMG Channels';
            app.EMGAxes_1 = uiaxes(app.EMGGridLayout);
            title(app.EMGAxes_1, 'EMG CH1')
            xlabel(app.EMGAxes_1, 'Time (s)')
            ylabel(app.EMGAxes_1, 'Amplitude')
            zlabel(app.EMGAxes_1, 'Z')
            app.EMGAxes_2 = uiaxes(app.EMGGridLayout);
            title(app.EMGAxes_2, 'EMG CH2')
            xlabel(app.EMGAxes_2, 'Time (s)')
            ylabel(app.EMGAxes_2, 'Amplitude')
            zlabel(app.EMGAxes_2, 'Z')
            app.EMGAxes_3 = uiaxes(app.EMGGridLayout);
            title(app.EMGAxes_3, 'EMG CH3')
            xlabel(app.EMGAxes_3, 'Time (s)')
            ylabel(app.EMGAxes_3, 'Amplitude')
            zlabel(app.EMGAxes_3, 'Z')
            app.EMGAxes_4 = uiaxes(app.EMGGridLayout);
            title(app.EMGAxes_4, 'EMG CH4')
            xlabel(app.EMGAxes_4, 'Time (s)')
            ylabel(app.EMGAxes_4, 'Amplitude')
            zlabel(app.EMGAxes_4, 'Z')
            app.EMGChannelsLabel = uilabel(app.EMGGridLayout);
            app.EMGChannelsLabel.Text = 'EMG Channels';
            app.EMGChannelListBox = uilistbox(app.EMGGridLayout);
            app.EMGChannelListBox.Items = {'1', '2', '3', '4'};
            app.EMGChannelListBox.Multiselect = 'on';
            app.EMGChannelListBox.Value = {};
            app.YmaxLabel_3 = uilabel(app.EMGGridLayout);
            app.YmaxLabel_3.Text = 'Y max';
            app.EMGYMaxEditField = uieditfield(app.EMGGridLayout, 'numeric');
            app.YminLabel_3 = uilabel(app.EMGGridLayout);
            app.YminLabel_3.Text = 'Y min';
            app.EMGYMinEditField = uieditfield(app.EMGGridLayout, 'numeric');
            app.AnalogGridLayout = uitab(app.TabGroup);
            app.AnalogGridLayout.Title = 'Analog Channels';
            app.AnalogAxes_1 = uiaxes(app.AnalogGridLayout);
            title(app.AnalogAxes_1, 'Title')
            xlabel(app.AnalogAxes_1, 'Time (s)')
            ylabel(app.AnalogAxes_1, 'Amplitude')
            zlabel(app.AnalogAxes_1, 'Z')
            app.AnalogAxes_2 = uiaxes(app.AnalogGridLayout);
            title(app.AnalogAxes_2, 'Title')
            xlabel(app.AnalogAxes_2, 'Time (s)')
            ylabel(app.AnalogAxes_2, 'Amplitude')
            zlabel(app.AnalogAxes_2, 'Z')
            app.AnalogChannelListBoxLabel = uilabel(app.AnalogGridLayout);
            app.AnalogChannelListBoxLabel.Text = 'Analog Channel';
            app.AnalogChannelListBox = uilistbox(app.AnalogGridLayout);
            app.AnalogChannelListBox.Items = {'1', '2', '3', '4'};
            app.AnalogChannelListBox.Multiselect = 'on';
            app.AnalogChannelListBox.Value = {'1'};
            app.YmaxLabel_2 = uilabel(app.AnalogGridLayout);
            app.YmaxLabel_2.Text = 'Y max';
            app.AnalogYMaxEditField = uieditfield(app.AnalogGridLayout, 'numeric');
            app.YminLabel_2 = uilabel(app.AnalogGridLayout);
            app.YminLabel_2.Text = 'Y min';
            app.AnalogYMinEditField = uieditfield(app.AnalogGridLayout, 'numeric');
            app.CombinedGridLayout = uitab(app.TabGroup);
            app.CombinedGridLayout.Title = 'Combined';
            app.CombinedAxes = uiaxes(app.CombinedGridLayout);
            title(app.CombinedAxes, 'Title')
            xlabel(app.CombinedAxes, 'Time (s)')
            ylabel(app.CombinedAxes, 'Amplitude')
            zlabel(app.CombinedAxes, 'Z')
            app.CombinedChannelListBoxLabel = uilabel(app.CombinedGridLayout);
            app.CombinedChannelListBoxLabel.Text = 'Combined Channel';
            app.CombinedChannelListBox = uilistbox(app.CombinedGridLayout);
            app.CombinedChannelListBox.Items = {'EMG1', 'EMG2', 'EMG3', 'EMG4', 'ANA1', 'ANA2'};
            app.CombinedChannelListBox.Multiselect = 'on';
            app.CombinedChannelListBox.Value = {'EMG1'};
            app.YminLabel = uilabel(app.CombinedGridLayout);
            app.YminLabel.Text = 'Y min';
            app.CombinedYMinEditField = uieditfield(app.CombinedGridLayout, 'numeric');
            app.YmaxLabel = uilabel(app.CombinedGridLayout);
            app.YmaxLabel.Text = 'Y max';
            app.CombinedYMaxEditField = uieditfield(app.CombinedGridLayout, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAQ_App_new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end